classdef semester_project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Container       matlab.ui.Figure            % Semester Project
        LabelEditField  matlab.ui.control.Label     % Data File Location
        DataFile        matlab.ui.control.EditField
        LabelEditField2 matlab.ui.control.Label     % Output File Name
        OutputFile      matlab.ui.control.EditField
        Button          matlab.ui.control.Button    % Calculate
        Browse          matlab.ui.control.Button    % Browse...
        Label           matlab.ui.control.Label     % Errors:
        LabelEditField3 matlab.ui.control.Label     % Username
        Username        matlab.ui.control.EditField
        BrowseOutput    matlab.ui.control.Button    % Browse...
        Label2          matlab.ui.control.Label     % Output File Folder
        OutputFolder    matlab.ui.control.EditField
        Label3          matlab.ui.control.Label     % Working...
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Username.Value = getenv('username');
            app.DataFile.Value = strcat(pwd,'\input.txt');
            app.OutputFolder.Value = pwd;
            app.OutputFile.Value = 'data.txt';
            app.Label3.Text = 'Ready';
        end

        % Browse button pushed function
        function browseFile(app)
            [name,folder] = uigetfile({'*.txt'; '*.mat'},'File Selector');
            if(name ~= 0)
                app.DataFile.Value = strcat(folder,name);
            end
        end

        % BrowseOutput button pushed function
        function browseFolder(app)
            [folder] = uigetdir('Folder Selector');
            if(folder ~= 0)
                app.OutputFolder.Value = folder;
            end
        end

        % Button button pushed function
        function calculate(app)
            calcuhelper(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Container = uifigure;
            app.Container.Name = 'Semester Project';
            setAutoResize(app, app.Container, true)
            app.LabelEditField = uilabel(app.Container);
            app.LabelEditField.Text = 'Data File Location';
            app.DataFile = uieditfield(app.Container, 'text');
            app.LabelEditField2 = uilabel(app.Container);
            app.LabelEditField2.Text = 'Output File Name';
            app.OutputFile = uieditfield(app.Container, 'text');
            app.Button = uibutton(app.Container, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @calculate);
            app.Button.Text = 'Calculate';
            app.Browse = uibutton(app.Container, 'push');
            app.Browse.ButtonPushedFcn = createCallbackFcn(app, @browseFile);
            app.Browse.Text = 'Browse...';
            app.Label = uilabel(app.Container);
            app.Label.Text = 'Errors:';
            app.LabelEditField3 = uilabel(app.Container);
            app.LabelEditField3.Text = 'Username';
            app.Username = uieditfield(app.Container, 'text');
            app.BrowseOutput = uibutton(app.Container, 'push');
            app.BrowseOutput.ButtonPushedFcn = createCallbackFcn(app, @browseFolder);
            app.BrowseOutput.Text = 'Browse...';
            app.Label2 = uilabel(app.Container);
            app.Label2.Text = 'Output File Folder';
            app.OutputFolder = uieditfield(app.Container, 'text');
            app.Label3 = uilabel(app.Container);
            app.Label3.Text = 'Working...';
        end
    end

    methods (Access = public)

        % Construct app
        function app = semester_project()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Container)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Container)
        end
    end
end