classdef semester_project_b < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Container        matlab.ui.Figure
        LabelEditField   matlab.ui.control.Label
        DataFile         matlab.ui.control.EditField
        LabelEditField2  matlab.ui.control.Label
        OutputFile       matlab.ui.control.EditField
        Button           matlab.ui.control.Button
        Browse           matlab.ui.control.Button
        Label            matlab.ui.control.Label
        LabelEditField3  matlab.ui.control.Label
        Username         matlab.ui.control.EditField
        BrowseOutput     matlab.ui.control.Button
        Label2           matlab.ui.control.Label
        OutputFolder     matlab.ui.control.EditField
        Label3           matlab.ui.control.Label
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Username.Value = getenv('username');
            app.DataFile.Value = strcat(pwd,'\input.txt');
            app.OutputFolder.Value = pwd;
            app.OutputFile.Value = 'stats.txt';
            app.Label3.Text = 'Ready';
        end

        % Button pushed function: Browse
        function browseFile(app, event)
            [name,folder] = uigetfile({'*.txt'; '*.mat'},'File Selector');
            if(name ~= 0)
                app.DataFile.Value = strcat(folder,name);
            end
        end

        % Button pushed function: BrowseOutput
        function browseFolder(app, event)
            [folder] = uigetdir('Folder Selector');
            if(folder ~= 0)
                app.OutputFolder.Value = folder;
            end
        end

        % Button pushed function: Button
        function calculate(app, event)
            calcuhelper(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Container = uifigure;
            app.Container.Name = 'Semester Project';
            setAutoResize(app, app.Container, true)
            app.LabelEditField = uilabel(app.Container);
            app.LabelEditField.Text = 'Data File Location';
            app.DataFile = uieditfield(app.Container, 'text');
            app.LabelEditField2 = uilabel(app.Container);
            app.LabelEditField2.Text = 'Output File Name';
            app.OutputFile = uieditfield(app.Container, 'text');
            app.Button = uibutton(app.Container, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @calculate, true);
            app.Button.Text = 'Calculate';
            app.Browse = uibutton(app.Container, 'push');
            app.Browse.ButtonPushedFcn = createCallbackFcn(app, @browseFile, true);
            app.Browse.Text = 'Browse...';
            app.Label = uilabel(app.Container);
            app.Label.Text = 'Errors:';
            app.LabelEditField3 = uilabel(app.Container);
            app.LabelEditField3.Text = 'Username';
            app.Username = uieditfield(app.Container, 'text');
            app.BrowseOutput = uibutton(app.Container, 'push');
            app.BrowseOutput.ButtonPushedFcn = createCallbackFcn(app, @browseFolder, true);
            app.BrowseOutput.Text = 'Browse...';
            app.Label2 = uilabel(app.Container);
            app.Label2.Text = 'Output File Folder';
            app.OutputFolder = uieditfield(app.Container, 'text');
            app.Label3 = uilabel(app.Container);
            app.Label3.Text = 'Working...';
        end
    end

    methods (Access = public)

        % Construct app
        function app = semester_project_b()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Container)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Container)
        end
    end
end